classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        animacion                      matlab.ui.control.CheckBox
        nivelesContorno                matlab.ui.control.Spinner
        NivelesdecontornoSpinnerLabel  matlab.ui.control.Label
        contorno                       matlab.ui.control.CheckBox
        PreferenciasLabel              matlab.ui.control.Label
        VistaLabel                     matlab.ui.control.Label
        vista3D                        matlab.ui.control.CheckBox
        vista2D                        matlab.ui.control.CheckBox
        Simular                        matlab.ui.control.Button
        estadoGlob                     matlab.ui.control.EditField
        EstadoEditFieldLabel           matlab.ui.control.Label
        cantidadCargas                 matlab.ui.control.Spinner
        NoDeCargasSpinnerLabel         matlab.ui.control.Label
        ZCoordinateGlob                matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YCoordinateGlob                matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        CoordenadasdelglbuloLabel      matlab.ui.control.Label
        XCoordinateGlob                matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        sizeNegPlate                   matlab.ui.control.Slider
        TamaoplacanegativaSliderLabel  matlab.ui.control.Label
        separacionPlacas               matlab.ui.control.Slider
        SeparacindeplacasSliderLabel   matlab.ui.control.Label
        grafica                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = placas(dist_cargas, largo, ancho, anchoneg, largoneg)
            % Dibujar placa positiva
            vx = 1;
            vy = 0.5;
            vz = 1
            d = dist_cargas/2
            vertices = [-d*ancho vy+largo/2 vz;        % Vértice 1
                        -d+vx*ancho vy+largo/2 vz;     % Vértice 2
                        -d*ancho vy+largo/2 -vz;       % Vértice 3
                         -d+vx*ancho vy+largo/2 -vz;   % Vértice 4
                        -d*ancho -(vy+largo/2) vz;     % Vértice 5
                        -d+vx*ancho -(vy+largo/2) vz;  % Vértice 6
                        -d*ancho -(vy+largo/2) -vz;    % Vértice 7
                        -d+vx*ancho -(vy+largo/2) -vz;]; % Vértice 8
            
            %Definir el polígono (patch) usando los vértices que se declararon
            %anteriormente
            caras = [1 2 4 3;
                     7 5 6 8;
                     1 2 6 5;
                     7 8 4 3;
                     6 2 4 8;
                     1 3 7 5];
            
            patch(app.grafica,'Faces',caras,'Vertices',vertices,'FaceColor','r'); %Dibujar prisma rojo
            
            % Dibujar placas negativa
            vertices = [d*anchoneg-vx vy+largoneg/2 vz;     % Vértice 1
                        d*anchoneg vy+largoneg/2 vz;        % Vértice 2
                        d*anchoneg-vx vy+largoneg/2 -vz;    % Vértice 3
                         d*anchoneg vy+largoneg/2 -vz;      % Vértice 4
                        d*anchoneg-vx -(vy+largoneg/2) vz;  % Vértice 5
                        d*anchoneg -(vy+largoneg/2) vz;     % Vértice 6
                        d*anchoneg-vx -(vy+largoneg/2) -vz; % Vértice 7
                         d*anchoneg -(vy+largoneg/2) -vz;]  % Vértice 8
            
            patch(app.grafica, 'Faces',caras,'Vertices',vertices,'FaceColor','b'); %Dibujar prisma rojo
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simular
        function SimularButtonPushed(app, event)
            cla(app.grafica, "reset") % Limpia el gráfico cada vez que se simula
            hold on
            
            % Definir las dimensiones de las placas
            % Placa positiva
            alto = 2; ancho = 1; largo = 5;
            % Placa negativa
            altoneg = 1; anchoneg = 1; largoneg = app.sizeNegPlate.Value;
            
            % Definir las variables para las cargas
            q_pos = 1e-9; % Carga positiva
            q_neg = -1e-9; % Carga negativa
            k_coulomb = 9e9; % Constante de Coulomb
            num_cargas = app.cantidadCargas.Value; % Número de cargas por placa
            dist_cargas = app.separacionPlacas.Value; % Distancia entre las placas
            
            dm = 0.5;
            % Crear las mallas para las coordenadas
            [x,y,z] = meshgrid(-5:dm:5, -5:dm:5, -alto:dm:alto);
            
            % Calcular las coordenadas de las cargas
            % Positivas (rojas)
            ubixr = -dist_cargas/2 + ancho/2;
            ubizr = 0;
            if num_cargas == 1
                ubiyr = 0;
            else
                ubiyr = linspace(-largo/2, largo/2, num_cargas);
            end
            % Negativas (azules)
            ubixa = dist_cargas/2 - anchoneg/2;
            ubiza = 0;
            if num_cargas == 1
                ubiya = 0;
            else
                ubiya = linspace(-largoneg/2, largoneg/2, num_cargas);
            end

            % Crear matrices para almacenar las componentes del campo eléctrico en cada punto de la malla
            Exf = zeros(size(x));
            Eyf = zeros(size(x));
            Ezf = zeros(size(x));
            
            % Ciclo que se ejecuta por cada carga
            for i = 1:num_cargas
            
                % Calcular las componentes del campo eléctrico creado por la carga positiva
                r3 = sqrt((x-ubixr).^2+(y-ubiyr(i)).^2+(z-ubizr).^2).^3;
                Ex = k_coulomb*q_pos.*(x-ubixr)./r3;
                Ey = k_coulomb*q_pos.*(y-ubiyr(i))./r3;
                Ez = k_coulomb*q_pos.*(z-ubizr)./r3;
            
                % Calcular las componentes del campo eléctrico creado por la carga negativa
                r3n = sqrt((x-ubixa).^2+(y-ubiya(i)).^2+(z-ubiza).^2).^3;
                Exn = k_coulomb*q_neg.*(x-ubixa)./r3n;
                Eyn = k_coulomb*q_neg.*(y-ubiya(i))./r3n;
                Ezn = k_coulomb*q_neg.*(z-ubiza)./r3n;
            
                % Calcular el campo eléctrico total generado por todas las cargas
                Exf = Exf + Ex + Exn;
                Eyf = Eyf + Ey + Eyn;
                Ezf = Ezf + Ez + Ezn;
                % Graficar las cargas
                plot3(app.grafica,ubixr,ubiyr,ubizr,"w +");
                plot3(app.grafica, ubixr,ubiyr,ubizr,"k o","Markersize",10);
                plot3(app.grafica, ubixa,ubiya,ubiza,"w _");
                plot3(app.grafica, ubixa,ubiya,ubiza,"k o","Markersize",10);
            
            end
            
            % Calcular el vector de campo eléctrico
            Ef = sqrt((Exf).^2+(Eyf).^2+(Ezf).^2);
            U = Exf./Ef;
            V = Eyf./Ef;
            W = Ezf./Ef;
            
            % Activar solo una casilla de visualización
            if app.vista2D.Value == 1
                app.vista3D.Value = 0;
            else
                app.vista3D.Value = 1;
            end
            
            hold(app.grafica,"on")
            
            % Dibujar el vector de campo eléctrico
            if app.vista2D.Value == 1
                quiver(app.grafica,x,y,U,V)
                view(app.grafica, 2)
            else
                quiver3(app.grafica,x,y,z,U,V,W)
                view(app.grafica, 3)
            end
            
            % Dibujar placa positiva
            vx = 1;
            vy = 0.5;
            vz = 1;
            d = dist_cargas/2;
            vertices = [-d*ancho vy+largo/2 vz;        % Vértice 1
                        -d+vx*ancho vy+largo/2 vz;     % Vértice 2
                        -d*ancho vy+largo/2 -vz;       % Vértice 3
                         -d+vx*ancho vy+largo/2 -vz;   % Vértice 4
                        -d*ancho -(vy+largo/2) vz;     % Vértice 5
                        -d+vx*ancho -(vy+largo/2) vz;  % Vértice 6
                        -d*ancho -(vy+largo/2) -vz;    % Vértice 7
                        -d+vx*ancho -(vy+largo/2) -vz]; % Vértice 8
            
            %Definir el polígono (patch) usando los vértices que se declararon
            %anteriormente
            caras = [1 2 4 3;
                     7 5 6 8;
                     1 2 6 5;
                     7 8 4 3;
                     6 2 4 8;
                     1 3 7 5];
            
            patch(app.grafica,'Faces',caras,'Vertices',vertices,'FaceColor','r'); %Dibujar prisma rojo
            
            % Dibujar placas negativa
            vertices = [d*anchoneg-vx vy+largoneg/2 vz;     % Vértice 1
                        d*anchoneg vy+largoneg/2 vz;        % Vértice 2
                        d*anchoneg-vx vy+largoneg/2 -vz;    % Vértice 3
                         d*anchoneg vy+largoneg/2 -vz;      % Vértice 4
                        d*anchoneg-vx -(vy+largoneg/2) vz;  % Vértice 5
                        d*anchoneg -(vy+largoneg/2) vz;     % Vértice 6
                        d*anchoneg-vx -(vy+largoneg/2) -vz; % Vértice 7
                         d*anchoneg -(vy+largoneg/2) -vz];  % Vértice 8
            
            patch(app.grafica, 'Faces',caras,'Vertices',vertices,'FaceColor','b'); %Dibujar prisma rojo


            % Calcular las curvas de nivel del campo eléctrico
            if app.contorno.Value == 1
                n_levels = 15; % Número de niveles de contorno
                contour(app.grafica, x(:,:,1), y(:,:,1), Ef(:,:,round(end/2)), n_levels, 'linewidth', 1.5)
            end

            % Globulo rojo
            % Posiciones del glóbulo a analizar
            if app.animacion.Value == 0
                globX= rand(1,1) - randi([fix(-d+vx*ancho) fix(d*anchoneg)],1,1);
                globY = rand(1,1) - randi([-fix((vy+largo/2)) 1+fix(vy+largo/2)],1,1);
                globZ = rand(1,1) - randi([-fix(vz) fix(vz)],1,1);
                app.XCoordinateGlob.Value = globX;
                app.YCoordinateGlob.Value = globY;
                app.ZCoordinateGlob.Value = globZ;
                plot3(app.grafica, globX,globY,globZ,"r o","Markersize",10, "MarkerFaceColor","r");
                d_test = ((d+vx*ancho) - (d*anchoneg))/2;
                if globX >= d_test
                    app.estadoGlob.Value = "Infectado";
                else
                    app.estadoGlob.Value = "Sano";
                end
            else
                while app.animacion.Value  ~= 0
                    globX= rand(1,1) - randi([fix(-d*ancho+vx+0.5) fix(d*anchoneg+vx-0.5)],1,1);
                    globY = rand(1,1) - randi([-fix((vy+largo/2)) 1+fix(vy+largo/2)],1,1);
                    globZ = rand(1,1) - randi([-fix(vz) fix(vz)],1,1);
                    app.XCoordinateGlob.Value = globX;
                    app.YCoordinateGlob.Value = globY;
                    app.ZCoordinateGlob.Value = globZ;
                    plotglob = plot3(app.grafica, globX,globY,globZ,"r o","Markersize",10, "MarkerFaceColor","r");
                    d_test = 0.2;
                    if globX >= d_test
                        app.estadoGlob.Value = "Infectado";
                        state = false;
                    else
                        app.estadoGlob.Value = "Sano";
                        state = true;
                    end
                    pause(0.2);
                    delete(plotglob);
                    v_animated = -(vy+largo)+1:0.1:globY;
                    posX_neg = linspace(globX, d*anchoneg-vx-0.4, length(v_animated));
                    posX_pos = linspace(globX, -d*ancho+vx+0.4, length(v_animated));
                    posZ = linspace(globZ,(-vz+vz)/2,length(v_animated));
                    cont = 1;
                    
                    for i = globY:-0.1:-(vy+largo)+1
                        if state == true
                            plotglob_ani = plot3(app.grafica, posX_pos(cont),i,posZ(cont),"r o","Markersize",10, "MarkerFaceColor","r");
                        else
                            plotglob_ani = plot3(app.grafica, posX_neg(cont),i,posZ(cont),"r o","Markersize",10, "MarkerFaceColor","r");
                        end
                        pause(0.15);
                        delete(plotglob_ani);
                        cont = cont + 1;
                        
                    end
                    

                end
            end
            
            
        end

        % Button down function: grafica
        function graficaButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.grafica.ButtonDownFcn = createCallbackFcn(app, @graficaButtonDown, true);
            app.SeparacindeplacasSliderLabel = uilabel(app.UIFigure);
            app.SeparacindeplacasSliderLabel.Text = 'Separación de placas';
            app.separacionPlacas = uislider(app.UIFigure);
            app.separacionPlacas.Limits = [3 10];
            app.separacionPlacas.Value = 5;
            app.TamaoplacanegativaSliderLabel = uilabel(app.UIFigure);
            app.TamaoplacanegativaSliderLabel.Text = 'Tamaño placa negativa';
            app.sizeNegPlate = uislider(app.UIFigure);
            app.sizeNegPlate.Limits = [0.5 6];
            app.sizeNegPlate.Value = 5;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XCoordinateGlob = uieditfield(app.UIFigure, 'numeric');
            app.XCoordinateGlob.Editable = 'off';
            app.CoordenadasdelglbuloLabel = uilabel(app.UIFigure);
            app.CoordenadasdelglbuloLabel.Text = 'Coordenadas del glóbulo';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YCoordinateGlob = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateGlob.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZCoordinateGlob = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateGlob.Editable = 'off';
            app.NoDeCargasSpinnerLabel = uilabel(app.UIFigure);
            app.NoDeCargasSpinnerLabel.Text = 'No. De Cargas';
            app.cantidadCargas = uispinner(app.UIFigure);
            app.cantidadCargas.Limits = [1 5];
            app.cantidadCargas.Value = 1;
            app.EstadoEditFieldLabel = uilabel(app.UIFigure);
            app.EstadoEditFieldLabel.Text = 'Estado';
            app.estadoGlob = uieditfield(app.UIFigure, 'text');
            app.estadoGlob.Editable = 'off';
            app.Simular = uibutton(app.UIFigure, 'push');
            app.Simular.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.Simular.Text = 'Simular';
            app.vista2D = uicheckbox(app.UIFigure);
            app.vista2D.Text = '2D';
            app.vista2D.Value = true;
            app.vista3D = uicheckbox(app.UIFigure);
            app.vista3D.Text = '3D';
            app.VistaLabel = uilabel(app.UIFigure);
            app.VistaLabel.Text = 'Vista';
            app.PreferenciasLabel = uilabel(app.UIFigure);
            app.PreferenciasLabel.Text = 'Preferencias';
            app.contorno = uicheckbox(app.UIFigure);
            app.contorno.Text = 'Contorno';
            app.NivelesdecontornoSpinnerLabel = uilabel(app.UIFigure);
            app.NivelesdecontornoSpinnerLabel.Text = 'Niveles de contorno';
            app.nivelesContorno = uispinner(app.UIFigure);
            app.nivelesContorno.Limits = [5 50];
            app.nivelesContorno.Value = 5;
            app.animacion = uicheckbox(app.UIFigure);
            app.animacion.Text = 'Animación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end